classdef SoundShapesApp_v2_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        InstructionsTab             matlab.ui.container.Tab
        TextArea_3                  matlab.ui.control.TextArea
        ExportFormatTab             matlab.ui.container.Tab
        UnitsPanel                  matlab.ui.container.Panel
        ButtonGroup                 matlab.ui.container.ButtonGroup
        mButton                     matlab.ui.control.ToggleButton
        cmButton                    matlab.ui.control.ToggleButton
        mmButton                    matlab.ui.control.ToggleButton
        MeasurementsPanel           matlab.ui.container.Panel
        NormalizedshapeButton       matlab.ui.control.StateButton
        DrawnshapeButton            matlab.ui.control.StateButton
        CalibrationLengthButton     matlab.ui.control.StateButton
        UnscaledheightButton        matlab.ui.control.StateButton
        ScaledheightButton          matlab.ui.control.StateButton
        UnscaledshapeButton         matlab.ui.control.StateButton
        ScaledshapeButton           matlab.ui.control.StateButton
        BodyLengthButton            matlab.ui.control.StateButton
        MetadataPanel               matlab.ui.container.Panel
        AnimalIDButton              matlab.ui.control.StateButton
        TaxaButton                  matlab.ui.control.StateButton
        OrientationButton           matlab.ui.control.StateButton
        DateButton                  matlab.ui.control.StateButton
        TowButton                   matlab.ui.control.StateButton
        SurveyButton                matlab.ui.control.StateButton
        OptionsTab                  matlab.ui.container.Tab
        Select_image                matlab.ui.control.Button
        Select_save                 matlab.ui.control.Button
        CreditsTab                  matlab.ui.container.Tab
        TextArea_4                  matlab.ui.control.TextArea
        MeasurementButtonGroup      matlab.ui.container.ButtonGroup
        SegmentBin                  matlab.ui.control.NumericEditField
        SegmentsnEditFieldLabel     matlab.ui.control.Label
        CalLenPx                    matlab.ui.control.EditField
        CalLenPxEditFieldLabel      matlab.ui.control.Label
        MeasurementLabel            matlab.ui.control.Label
        BodyaxislineLabel           matlab.ui.control.Label
        CalibrationLabel            matlab.ui.control.Label
        MeasurementEdit             matlab.ui.control.Button
        BoxyAxisEdit                matlab.ui.control.Button
        CalibratIonEdit             matlab.ui.control.Button
        ExportMeasurementButton     matlab.ui.control.Button
        Export_lamp                 matlab.ui.control.Lamp
        Measurement_lamp            matlab.ui.control.Lamp
        Body_axis_lamp              matlab.ui.control.Lamp
        Calibration_lamp            matlab.ui.control.Lamp
        CalLength                   matlab.ui.control.EditField
        CalibrationlengthmmEditFieldLabel  matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        OtherID                     matlab.ui.control.EditField
        OtherIDIfapplicableEditFieldLabel  matlab.ui.control.Label
        TaxaID                      matlab.ui.control.DropDown
        TaxonomicIDLabel            matlab.ui.control.Label
        Orientation                 matlab.ui.control.DropDown
        OrientationLabel            matlab.ui.control.Label
        Tow                         matlab.ui.control.EditField
        TowEditFieldLabel           matlab.ui.control.Label
        AnimalID                    matlab.ui.control.EditField
        AnimalIDEditFieldLabel      matlab.ui.control.Label
        Date                        matlab.ui.control.EditField
        DateEditFieldLabel          matlab.ui.control.Label
        Survey                      matlab.ui.control.EditField
        SurveyEditFieldLabel        matlab.ui.control.Label
        MeasurementMode             matlab.ui.control.DropDown
        MeasurementModeLabel        matlab.ui.control.Label
        Soundshapes_label           matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        Clear_All                   matlab.ui.control.Button
        Clear_Preview               matlab.ui.control.Button
        Update_Data                 matlab.ui.control.Button
        Clear_Image                 matlab.ui.control.Button
        ImageMetadataPanel          matlab.ui.container.Panel
        Image                       matlab.ui.control.EditField
        CurrentImageEditFieldLabel  matlab.ui.control.Label
        Directory                   matlab.ui.control.EditField
        CurrentDirectoryLabel       matlab.ui.control.Label
        Height_px                   matlab.ui.control.EditField
        HeightpxEditFieldLabel      matlab.ui.control.Label
        Width_px                    matlab.ui.control.EditField
        WidthpxEditFieldLabel       matlab.ui.control.Label
        ImageDate                   matlab.ui.control.EditField
        ImageDateEditFieldLabel     matlab.ui.control.Label
        Filetype                    matlab.ui.control.EditField
        FormatEditFieldLabel        matlab.ui.control.Label
        Filename                    matlab.ui.control.EditField
        FileEditFieldLabel          matlab.ui.control.Label
        ImageProccesedDataPanel     matlab.ui.container.Panel
        SegCount                    matlab.ui.control.EditField
        SegmentsnLabel              matlab.ui.control.Label
        Length_px                   matlab.ui.control.EditField
        LengthpxLabel               matlab.ui.control.Label
        Length_mm                   matlab.ui.control.EditField
        LengthmmEditFieldLabel      matlab.ui.control.Label
        PxLength                    matlab.ui.control.EditField
        PixelsmmEditFieldLabel      matlab.ui.control.Label
        MeasurementPreviewLabel     matlab.ui.control.Label
        Directory_label             matlab.ui.control.Label
        ImagePreview                matlab.ui.control.UIAxes
        ImageAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %General directory variables
        execFolder; %local directory
        savefolder; %custom save directory
        Version; %software version
        %Measurement variables 
        seps1; seps2; %Dorsal and ventral x-axis
        ya_obs; yb_obs; %Dorsal and ventral y-axis
        x_obs; y_obs; %Full normalized x- and y-axis
        l_obs; %Body offset line
        xd_true; xv_true;  %Non-normalized dorsal and ventral x-axis
        yd_true; yv_true; %Non-normalized dorsal and ventral y-axis
        x_true; y_true; %Full non-normalized x- and y-axis
        ld_true; %Non-normalized drawn body line
        bodyshape; %bodyshape object
        bodyline; %bodyline plot object 
        y_seg; %y-axis positions for body segment lines 
        save_vars = []; %variables to be written with export
    end
        
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %STARTUP FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %Collect local directory
        function [executableFolder] = GetExecutableFolder(app) %#ok<MANU> 
        	try
        		if isdeployed 
        			% User is running an executable in standalone mode. 
        			[~, result] = system('set PATH');
        			executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
        		else
        			% User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
        			executableFolder = pwd; 
        		end 
        	catch ME
        		errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
        			ME.stack(1).name, ME.stack(1).line, ME.message);
        		uiwait(warndlg(errorMessage));
            end
        end
        %Collect metadata useful for future sessions (REQUIRES MORE WORK!)
        function metacheck(app)
            %Collect working directory 
            searching = char(strcat(app.execFolder.Value, '\docs\meta.csv'));
            %Search
            if isfile(searching)
                meta1 = readtable(searching); 
                
                if isempty(meta1.W_dir)
                    meta1.W_dir = app.execFolder.Value;
                elseif ~strcmp(char(app.execFolder.Value), char(meta1.W_dir))
                    meta1.W_dir = app.execFolder.Value;
                else
                    app.execFolder.Value = char(meta1.W_dir);
                end 
                
                if isempty(meta1.Save_dir) 
                    meta1.Save_dir = app.savefolder;
                elseif ~exist(char(meta1.Save_dir))
                    app.savefolder = app.execFolder.Value;
                else
                    app.savefolder = char(meta1.Save_dir);
                end
                
                if isempty(meta1.Version) || ~isstring(meta1.Version)
                    meta1.Version = app.Version;
                end
                
                meta1.Last_date = today('datetime');
                
                writetable(meta1, searching);
            else
                meta1 = table();
                meta1.W_dir = char(app.execFolder.Value);
                meta1.Save_dir = char(app.execFolder.Value);
                meta1.Version = char(app.Version);
                meta1.Last_date = today('datetime');
                writetable(meta1, searching);
            end
        end
        %Update initial image with software logo
        function updateimage_initial(app)
            %Find file path to initial image; read in
            file2path = char(strcat(app.execFolder.Value, '\docs\Soundshapes_logo_initial.png')); 
            %Read in bit info
            im = imread(file2path);
            %Print image
            imagesc(app.ImageAxes, im);          
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %PLOTTING FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %Call in measurement image 
        function updateimage(app, imagefile)
            %Read in new image
            im = imread(imagefile);
            %Print
            imagesc(app.ImageAxes, im);
        end
        %Collect measurement image metadata
        function imagemetadata(app,imagefile)
            info = imfinfo(imagefile);
            app.Directory.Value = char(app.execFolder.Value);
            app.Image.Value = info.Filename;
            app.Filename.Value = info.Filename;
            app.Filetype.Value = info.Format;
            app.ImageDate.Value = info.DateTime;
            app.Width_px.Value = num2str(info.Width);
            app.Height_px.Value = num2str(info.Height);            
        end  
        %Generate preview image 
        function previewgen(app)
            %number of bins to break up shape by
            n_sub = app.SegmentBin.Value;
            %parse the body shape positions
            x_open = app.bodyshape(:,1); y_open = app.bodyshape(:,2);
            x_closed = [x_open; x_open(1)]; y_closed = [y_open; y_open(1)];
            %normalize the data to the max length of the animal
            %X-axis [0,1], Y-axis [-1,1]
            x_scaled = (x_closed - min(x_closed)) / max(x_closed - min(x_closed));
            y_scaled = y_closed / max(x_closed); 
            %parse the offset line, if applicable
            if isprop(app, 'bodyline') && ~ isempty(app.bodyline)
                %x-axis
                line_x = app.bodyline(:, 1); 
                line_x = (line_x - min(x_closed)) / (max(x_closed - min(x_closed)));
                %y-axis
                line_y = app.bodyline(:, 2);
                line_y = line_y / max(x_closed);
            else
                line_x = zeros(n_sub, 1); 
                line_y = zeros(n_sub, 1); 
                
            end
            %generate new bin space -- separate for orientation of animal
            new_bins = linspace(0, 1, n_sub+1); new_bins_rev = flip(new_bins);
            app.seps1 = new_bins; app.seps2 = new_bins_rev;
            %standardize orientation of shape to start and end shape at 0
            if x_scaled(1) ~= 0
                idx_begin = find(x_scaled == 0); 
                x_scaled = [x_scaled(max(idx_begin):end); x_scaled(1:min(idx_begin))]; 
                y_scaled = [y_scaled(max(idx_begin):end); y_scaled(1:min(idx_begin))]; 
            end
            %break up the shape into its two halves
            x_mx_idx = find(x_scaled == max(x_scaled));
            
            if length(x_mx_idx) == 2
                x_dorsal = x_scaled(1:x_mx_idx); %upper
                y_dorsal = y_scaled(1:x_mx_idx); %upper
                x_ventral = x_scaled((x_mx_idx+1):length(x_scaled)); %lower 
                y_ventral = y_scaled((x_mx_idx+1):length(y_scaled)); %lower 
            else
                x_dorsal = x_scaled(1:x_mx_idx); %upper
                y_dorsal = y_scaled(1:x_mx_idx); %upper
                x_ventral = x_scaled(x_mx_idx:length(x_scaled)); %lower 
                y_ventral = y_scaled(x_mx_idx:length(y_scaled)); %lower 
            end          
            %resample shape to change bin resolution
            yd = interp1(x_dorsal, y_dorsal, new_bins, 'linear'); %dorsal
            yv = interp1(x_ventral, y_ventral, new_bins_rev, 'linear'); %ventral
            ld = interp1(line_x, line_y, new_bins, 'linear', 'extrap'); %body length line (offset)
            %adjust the values for plotting 
            if median(yd) < median(yv) %dorsal lower than ventral
                yd = -yd; yv = -yv; ld = -ld;
            endl_off
            %set x- and y-axis to normalized (i.e. centered on '0')
            ya = yd - yd(1); yb = yv - yv(end); 
            app.yd_true = ya; app.yv_true = yb; 
            app.xd_true = new_bins; app.xv_true = new_bins_rev;
            app.ld_true = ld;
            %apply line offset, if it's there
            l_off = ld - ld(1); %normalize to 0-axiz
            app.l_obs = l_off; %save to environment
            ya_off = ya - l_off; yb_off = fliplr(yb) - l_off; %subtract line
            y_off = [ya_off fliplr(yb_off)]; %put into same matrix
            ya_off1 = ya_off(2:end-1); yb_off1 = yb_off(2:end-1); %body segments
            x_off = app.seps1(2:end-1);
            %x_full = [new_bins new_bins_rev];
            %save measurements to memory
            app.ya_obs = ya_off; app.yb_obs = yb_off;
            %app.seps1 = new_bins;
            app.y_obs = y_off; app.x_obs= x_off;
            app.y_seg = [ya_off1; yb_off1];
            %update preview
            updatepreview(app);
        end     
        %Update preview image 
        function updatepreview(app)
            %Clear previous image, if it is there 
            cla(app.ImagePreview);
            %begin plotting 
            hold(app.ImagePreview, 'on'); %hold for layers
            %plot crossline and non-offset shape
            plot(app.ImagePreview, app.seps1, app.l_obs + (-app.l_obs), ...
                'Color', 'black','LineStyle','--');
            plot(app.ImagePreview, [app.xd_true app.xv_true].', ...
                [app.yd_true app.yv_true].', ...
                'Color', [231/265,220/265,220/265], ...
                'LineWidth', 3);
            %plot main shape
            plot(app.ImagePreview, [app.seps1 app.seps2], app.y_obs, '-r');
            scatter(app.ImagePreview, [app.seps1 app.seps2], app.y_obs, 200, '.r');
            %add segment lines
            plot(app.ImagePreview, [app.x_obs; app.x_obs], ...
                app.y_seg, ...
                'Color', 'black');
            %set axes
            axis(app.ImagePreview, [0,1,-max(abs(app.y_obs))*3, max(abs(app.y_obs))*3]);
            %release
            hold(app.ImagePreview, 'off');
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %MEASUREMENT/DATA CLEARING FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %Clear measurement image
        function image_clear(app)
            %Reset lamps
            app.Calibration_lamp.Color = 'red';
            app.Body_axis_lamp.Color = 'red';
            app.Measurement_lamp.Color = 'red';
            app.Export_lamp.Color = 'red';
            %Clear image
            cla(app.ImageAxes);
            %Clear metadata 
            app.Length_mm.Value = '';
            app.Length_px.Value = '';
            app.SegCount.Value = '';
            app.PxLength.Value = '';
        end
        %Clear preview measurement image
        function preview_clear(app)
            %Reset lamps
            app.Measurement_lamp.Color = 'red';
            app.Export_lamp.Color = 'red';
            %Clear image
            cla(app.ImagePreview);
        end
        %Clear all 
        function clear_all(app)
            image_clear(app);
            preview_clear(app);
            app.Survey.Value = ''; app.Date.Value = ''; 
            app.Tow.Value = ''; app.AnimalID.Value = ''; 
            app.OtherID.Value = '';   
            app.Directory.Value = ''; app.Image.Value= '';
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %UTILITY FUNCTIONS 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function out = path_check(app, file_to_check) %#ok<INUSL> 
            filename = file_to_check;
            
            ct = 1;
            while ct > 0
                ct = 0;
                if contains(filename, "\\")
                    filename(strfind(filename, '\\'))='';
                    ct = ct + 1;
                elseif contains(filename, "//")
                    filename(strfind(filename, "//"))='';
                    ct = ct + 1;
                elseif contains(filename, '/')
                    filename(strfind(filename, '/'))='\';
                elseif contains(filename, '\/')
                    filename(strfind(filename, "\/"))='\';
                elseif contains(filename, '/\')
                    filename(strfind(filename, "/\"))='\';
                end
                
                if ct == 0 
                    break;
                end
                
            end
            out = filename;
        end
        
        function vars = export_table(app)
            vars = table();
            N_ID = app.SegmentBin.Value;
            
            % Value changed function: SurveyButton
            value = app.SurveyButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Survey"];
                vars.Survey = repmat(app.Survey.Value, N_ID+1, 1);
                if length(find(app.save_vars == "Survey")) > 1
                    id = find(app.save_vars == "Survey");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: TowButton
            value = app.TowButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Tow"];
                vars.Tow = repmat(app.Tow.Value, N_ID+1, 1);
                if length(find(app.save_vars == "Tow")) > 1
                    id = find(app.save_vars == "Tow");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: DateButton
            value = app.DateButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Date"];
                vars.Date = repmat(app.Date.Value, N_ID+1, 1);
                if length(find(app.save_vars == "Date")) > 1
                    id = find(app.save_vars == "Date");
                    app.save_vars(id(2:end)) = [];
                end
            end
            
        % Value changed function: AnimalIDButton
            value = app.AnimalIDButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Animal"];
                vars.Animal = repmat(app.AnimalID.Value, N_ID+1, 1);
                if length(find(app.save_vars == "Animal")) > 1
                    id = find(app.save_vars == "Animal");
                    app.save_vars(id(2:end)) = [];
                end
            end
            
        % Value changed function: OrientationButton
            value = app.OrientationButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; 'Orientation'];
                vars.Orientation = repmat(app.Orientation.Value, N_ID+1, 1);
                if length(find(app.save_vars == 'Orientation')) > 1
                    id = find(app.save_vars == "Orientation");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: TaxaButton
            value = app.TaxaButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Taxa"];
                
                 if ~isempty(app.OtherID.Value)
                    Ta_ID = app.OtherID.Value;
                else
                    Ta_ID = app.TaxaID.Value;
                end
                
                vars.Taxa = repmat(Ta_ID, N_ID+1, 1);
                
                if length(find(app.save_vars == "Taxa")) > 1
                    id = find(app.save_vars == "Taxa");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: BodyLengthButton
            value = app.BodyLengthButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Length"];
                vars.Length = repmat(str2double(app.Length_mm.Value), N_ID+1, 1);
                if length(find(app.save_vars == "Length")) > 1
                    id = find(app.save_vars == "Length");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: CalibrationLengthButton
            value = app.CalibrationLengthButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Calibration_m"];
                vars.Calibration_mm = repmat(str2double(app.CalLength.Value), N_ID+1, 1);
                if length(find(app.save_vars == "Calibration_m")) > 1
                    id = find(app.save_vars == "Calibration_m");
                    app.save_vars(id(2:end)) = [];
                end
            end

        % Value changed function: ScaledshapeButton
            value = app.ScaledshapeButton.Value;
             if value == 1
                app.save_vars = [app.save_vars; "Body"];
                vars.Body = app.seps1.';

                if length(find(app.save_vars == "Body")) > 1
                    id = find(app.save_vars == "Body");
                    app.save_vars(id(2:end)) = [];
                end
             end

        % Value changed function: UnscaledshapeButton
            value = app.UnscaledshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Body_m"];
                vars.Body_m = round(app.seps1.' * str2double(app.Length_mm.Value) * 1e-3, 4);
                if length(find(app.save_vars == "Body_m")) > 1
                    id = find(app.save_vars == "Body_m");
                    app.save_vars(id(2:end)) = [];
                end
            end            

        % Value changed function: ScaledheightButton
            value = app.ScaledheightButton.Value;
            if value == 1
                height = app.ya_obs - app.yb_obs;
                vars.Height_scaled = height.';
                app.save_vars = [app.save_vars; "Height_scaled"];
                if length(find(app.save_vars == "Height_scaled")) > 1
                    id = find(app.save_vars == "Height_scaled");
                    app.save_vars(id(2:end)) = [];
                end
            end   

        % Value changed function: UnscaledheightButton
            value = app.UnscaledheightButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Height_m"];
                height = app.ya_obs - app.yb_obs;
                vars.Height_m = round(height.' * str2double(app.Length_mm.Value) * 1e-3, 4);
                if length(find(app.save_vars == "Height_m")) > 1
                    id = find(app.save_vars == "Height_m");
                    app.save_vars(id(2:end)) = [];
                end
            end   
        % Value changed function: DrawnshapeButton
            value = app.DrawnshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Y_true"];
                if length(find(app.save_vars == "Y_true")) > 1
                    id = find(app.save_vars == "Y_true");
                    app.save_vars(id(2:end)) = [];
                end
            end   

        % Value changed function: YaxisNormalizedButton
            value = app.NormalizedshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Y_centered"];
                if length(find(app.save_vars == "Y_centered")) > 1
                    id = find(app.save_vars == "Y_centered");
                    app.save_vars(id(2:end)) = [];
                end  
            end
            
            %app.save_vars = [app.save_vars; "Y_low"]; 
            vars.Y_low = app.yb_obs.';
            %app.save_vars = [app.save_vars; "Y_high"];
            vars.Y_high = app.ya_obs.'; 
            %app.save_vars = [app.save_vars; "Midsection"];
            vars.Midsection = app.ld_true.'; 
            
            
        end
        
        function export(app)
            shape_out = export_table(app); 
            
            %Collate all metadata
            S_ID = app.Survey.Value;
            T_ID = app.Tow.Value;
            D_ID = app.Date.Value;
            A_ID = app.AnimalID.Value;
            O_ID = app.Orientation.Value;
                       
            %Import directory location
            Di_ID = app.savefolder;
            %Begin exporting for shape file
            %String name
            f_dim = {Di_ID,"\measurements\dimensions\",S_ID,T_ID,D_ID,A_ID,lower(O_ID)};
            f_dim_str = [sprintf('%s', f_dim{1:2}), sprintf('%s.', f_dim{3:end}), 'dimensions.csv'];
            f_dim_str_up = path_check(app, f_dim_str);
            %Export
            writetable(shape_out, f_dim_str_up); 
            %Save the representative figure
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.ImageAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.ImageAxes.XLim;
            figAxes.YLim = app.ImageAxes.YLim;
            figAxes.ZLim = app.ImageAxes.ZLim;
            set(figAxes, 'Units', 'Pixels');
            set(figAxes, 'YDir', 'reverse');
            %String name
            f_img = {Di_ID,"\QC\image\",S_ID,T_ID,D_ID,A_ID,lower(O_ID)};
            f_img_str = [sprintf('%s', f_img{1:2}), sprintf('%s.', f_img{3:end}), 'QCimage.jpg'];
            f_img_str_up = path_check(app, f_img_str); 
            saveas(fig, f_img_str_up);
            % Delete the temporary figure.
            delete(fig);
            % Create other figure 
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.ImagePreview.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.ImagePreview.XLim;
            figAxes.YLim = app.ImagePreview.YLim;
            figAxes.ZLim = app.ImagePreview.ZLim;
            figAxes.DataAspectRatio = app.ImagePreview.DataAspectRatio;
            f_shp = {Di_ID,"\QC\shape\",S_ID,T_ID,D_ID,A_ID,lower(O_ID)};
            f_shp_str = [sprintf('%s', f_shp{1:2}), sprintf('%s.', f_shp{3:end}), 'QCshape.jpg'];
            f_shp_str_up = path_check(app, f_shp_str);
            saveas(fig, f_shp_str_up);
            % Delete the temporary figure.
            delete(fig);
        end
    end      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set Version
            app.Version = '1.2';
            %Grab current executable folder for later saving
            app.execFolder.Value = GetExecutableFolder(app);
            %Set figure icon
            app.UIFigure.Icon = char(strcat(app.execFolder.Value, '\docs\Soundshapes_icon_24.png'));            
            %Check for metadata fileinfo
            metacheck(app);
            %Initialize application
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            %Load preview image
            updateimage_initial(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1033, 1033};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {413, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            %app.UIFigure.Visible = 'off';
            %app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               imagemetadata(app,[p f]);
            end
        end

        % Button pushed function: CalibratIonEdit
        function CalibratIonEditButtonPushed(app, event)
           %Draw calibration line
           calibration = drawline(app.ImageAxes, ...
               'Linewidth', 4, 'Color', 'red'); 
           %Collect coordinates
           xcal = calibration.Position(:, 1); 
           ycal = calibration.Position(:, 2); 
           %Calculate distance (px)
           cal_len = sqrt(abs(ycal(2) - ycal(1))^2 + abs(xcal(2) - xcal(1))^2);
           app.CalLenPx.Value = num2str(cal_len);
           %Calculate conversion from px to mm
           scale_px = cal_len / str2double(app.CalLength.Value);
           app.PxLength.Value = num2str(scale_px);
           %Update
           app.Calibration_lamp.Color = 'green';          
        end

        % Button pushed function: Clear_Image
        function Clear_ImagePushed(app, event)
            image_clear(app);
        end

        % Button pushed function: Update_Data
        function Update_DataPushed(app, event)
            c1 = get(app.ImageAxes, 'children');
            polygon = findobj(c1, 'Type', 'images.roi.polygon');
            app.bodyshape = [polygon.Position(:, 1) polygon.Position(:, 2)];
            body = findobj(c1, 'Type', 'images.roi.polyline');
            app.bodyline = [body.Position(:,1) body.Position(:,2)];
            previewgen(app); 
            calline = findobj(c1, 'Type', 'images.roi.line');
            xcal = calline.Position(:, 1);
            ycal = calline.Position(:, 2);
            length_line = body.Position; 
            length_est = sum(sqrt(sum(diff(length_line).^2, 2)));
            scale_px = sqrt(abs(ycal(2) - ycal(1))^2 + abs(xcal(2) - xcal(1))^2) / str2double(app.CalLength.Value);
            app.Length_px.Value = num2str(length_est);
            app.PxLength.Value = num2str(scale_px);
            app.Length_mm.Value = num2str(str2double(app.Length_px.Value) / str2double(app.PxLength.Value));
        end

        % Button pushed function: MeasurementEdit
        function MeasurementEditButtonPushed(app, event)
           app.Measurement_lamp.Color = 'green';
           hold(app.ImageAxes, 'on');
           k = drawpolygon(app.ImageAxes, ...
               'FaceSelectable',false, ...
               'Color',[65/255, 182/255, 196/255]);
           hold(app.ImageAxes, 'off');
           app.bodyshape = [k.Position(:, 1) k.Position(:, 2)];
           previewgen(app);
        end

        % Button pushed function: BoxyAxisEdit
        function BoxyAxisEditButtonPushed(app, event)
           hold(app.ImageAxes, 'on'); 
           m = drawpolyline(app.ImageAxes, 'Color', [12/255,44/255,132/255]); 
           hold(app.ImageAxes, 'off'); 
           app.bodyline = [m.Position(:, 1) m.Position(:,2)];
           %c1 = get(app.ImageAxes, 'children');
           %app.bodyline = findobj(c1, 'Type', 'images.roi.polyline');
           app.Body_axis_lamp.Color = 'green';
           length_line = m.Position; 
           length_est = sum(sqrt(sum(diff(length_line).^2, 2))); 
           app.Length_px.Value = num2str(length_est);
           app.Length_mm.Value = num2str(length_est / str2double(app.PxLength.Value));
           app.SegCount.Value = num2str(app.SegmentBin.Value);
        end

        % Value changed function: CalLength
        function CalLengthValueChanged(app, event)
            post_px = str2double(app.CalLenPx.Value) / str2double(app.CalLength.Value);
            app.PxLength.Value = num2str(post_px);
            value = str2double(app.Length_px) / str2double(app.PxLength.Value);
            app.Length_mm.Value = num2str(value);
        end

        % Button pushed function: ExportMeasurementButton
        function ExportMeasurementButtonPushed(app, event)
            app.Export_lamp.Color = 'green';
            export(app);
        end

        % Button pushed function: Clear_Preview
        function Clear_PreviewButtonPushed(app, event)
            preview_clear(app);
        end

        % Button pushed function: Clear_All
        function Clear_AllButtonPushed(app, event)
            clear_all(app);
        end

        % Button pushed function: Select_save
        function Select_saveButtonPushed(app, event)
            selpath = uigetdir(app.Directory);
            app.Directory = selpath; 
            app.savefolder.Value = selpath;            
        end

        % Value changed function: SurveyButton
        function SurveyButtonValueChanged(app, event)
            value = app.SurveyButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Survey"];
                if length(find(app.save_vars == "Survey")) > 1
                    id = find(app.save_vars == "Survey");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: TowButton
        function TowButtonValueChanged(app, event)
            value = app.TowButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Tow"];
                if length(find(app.save_vars == "Tow")) > 1
                    id = find(app.save_vars == "Tow");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: DateButton
        function DateButtonValueChanged(app, event)
            value = app.DateButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Date"];
                if length(find(app.save_vars == "Date")) > 1
                    id = find(app.save_vars == "Date");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: OrientationButton
        function OrientationButtonValueChanged(app, event)
            value = app.OrientationButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; 'Orientation'];
                if length(find(app.save_vars == 'Orientation')) > 1
                    id = find(app.save_vars == "Orientation");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: TaxaButton
        function TaxaButtonValueChanged(app, event)
            value = app.TaxaButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Taxa"];
                if length(find(app.save_vars == "Taxa")) > 1
                    id = find(app.save_vars == "Taxa");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: AnimalIDButton
        function AnimalIDButtonValueChanged(app, event)
            value = app.AnimalIDButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Animal"];
                if length(find(app.save_vars == "Animal")) > 1
                    id = find(app.save_vars == "Animal");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: BodyLengthButton
        function BodyLengthButtonValueChanged(app, event)
            value = app.BodyLengthButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Length"];
                if length(find(app.save_vars == "Length")) > 1
                    id = find(app.save_vars == "Length");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: CalibrationLengthButton
        function CalibrationLengthButtonValueChanged(app, event)
            value = app.CalibrationLengthButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Calibration_m"];
                if length(find(app.save_vars == "Calibration_m")) > 1
                    id = find(app.save_vars == "Calibration_m");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: ScaledshapeButton
        function ScaledshapeButtonValueChanged(app, event)
            value = app.ScaledshapeButton.Value;
             if value == 1
                app.save_vars = [app.save_vars; "Body"];
                if length(find(app.save_vars == "Body")) > 1
                    id = find(app.save_vars == "Body");
                    app.save_vars(id(2:end)) = [];
                end
            end
        end

        % Value changed function: UnscaledshapeButton
        function UnscaledshapeButtonValueChanged(app, event)
            value = app.UnscaledscaledshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Body_m"];
                if length(find(app.save_vars == "Body_m")) > 1
                    id = find(app.save_vars == "Body_m");
                    app.save_vars(id(2:end)) = [];
                end
            end            
        end

        % Value changed function: ScaledheightButton
        function ScaledheightButtonValueChanged(app, event)
            value = app.ScaledheightButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Height_scaled"];
                if length(find(app.save_vars == "Height_scaled")) > 1
                    id = find(app.save_vars == "Height_scaled");
                    app.save_vars(id(2:end)) = [];
                end
            end   
        end

        % Value changed function: UnscaledheightButton
        function UnscaledheightButtonValueChanged(app, event)
            value = app.UnscaledheightButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Height_m"];
                if length(find(app.save_vars == "Height_m")) > 1
                    id = find(app.save_vars == "Height_m");
                    app.save_vars(id(2:end)) = [];
                end
            end   
        end

        % Value changed function: DrawnshapeButton
        function DrawnshapeButtonValueChanged(app, event)
            value = app.DrawnshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Y_true"];
                if length(find(app.save_vars == "Y_true")) > 1
                    id = find(app.save_vars == "Y_true");
                    app.save_vars(id(2:end)) = [];
                end
            end   
        end

        % Value changed function: NormalizedshapeButton
        function NormalizedshapeButtonValueChanged(app, event)
            value = app.NormalizedshapeButton.Value;
            if value == 1
                app.save_vars = [app.save_vars; "Y_centered"];
                if length(find(app.save_vars == "Y_centered")) > 1
                    id = find(app.save_vars == "Y_centered");
                    app.save_vars(id(2:end)) = [];
                end
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Soundshapes';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Soundshapes_label = uilabel(app.LeftPanel);
            app.Soundshapes_label.Text = 'SOUNDSHAPES (v.1.2)';
            app.MeasurementModeLabel = uilabel(app.LeftPanel);
            app.MeasurementModeLabel.Text = 'Measurement Mode';
            app.MeasurementMode = uidropdown(app.LeftPanel);
            app.MeasurementMode.Items = {'Shape', 'Length (1)', 'Length (2+)'};
            app.MeasurementMode.Value = 'Shape';
            app.SurveyEditFieldLabel = uilabel(app.LeftPanel);
            app.SurveyEditFieldLabel.Text = 'Survey';
            app.Survey = uieditfield(app.LeftPanel, 'text');
            app.Survey.Value = 'Cruise';
            app.DateEditFieldLabel = uilabel(app.LeftPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.Date = uieditfield(app.LeftPanel, 'text');
            app.Date.Value = 'mmddyyyy';
            app.AnimalIDEditFieldLabel = uilabel(app.LeftPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalID = uieditfield(app.LeftPanel, 'text');
            app.AnimalID.Value = 'A01';
            app.TowEditFieldLabel = uilabel(app.LeftPanel);
            app.TowEditFieldLabel.Text = 'Tow';
            app.Tow = uieditfield(app.LeftPanel, 'text');
            app.Tow.Value = 'Haul';
            app.OrientationLabel = uilabel(app.LeftPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.Orientation = uidropdown(app.LeftPanel);
            app.Orientation.Items = {'Lateral', 'Dorsoventral', ''};
            app.Orientation.Value = 'Lateral';
            app.TaxonomicIDLabel = uilabel(app.LeftPanel);
            app.TaxonomicIDLabel.Text = 'Taxonomic ID';
            app.TaxaID = uidropdown(app.LeftPanel);
            app.TaxaID.Items = {'Amphipod', 'Bristlemouth', 'Clupeid fish', 'Eel', 'Flatfish', 'Hatchetfish', 'Jellyfish', 'Krill', 'Leptocephali', 'Lightfish', 'Lophogastrid', 'Myctophid', 'Mysid', 'Pteropod', 'Ridgehead', 'Salp', 'Shrimp', 'Snake mackerel', 'Squid', 'Other (UID)', 'Other Crustacean', 'Other Fish', 'Other Gastropod', 'Other Gelatinous'};
            app.TaxaID.Value = 'Krill';
            app.OtherIDIfapplicableEditFieldLabel = uilabel(app.LeftPanel);
            app.OtherIDIfapplicableEditFieldLabel.Text = {'Other ID'; '(If applicable)'};
            app.OtherID = uieditfield(app.LeftPanel, 'text');
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Select Image';
            app.MeasurementButtonGroup = uibuttongroup(app.LeftPanel);
            app.MeasurementButtonGroup.Title = 'Measurement';
            app.CalibrationlengthmmEditFieldLabel = uilabel(app.MeasurementButtonGroup);
            app.CalibrationlengthmmEditFieldLabel.Text = {'Calibration'; 'length (mm)'};
            app.CalLength = uieditfield(app.MeasurementButtonGroup, 'text');
            app.CalLength.ValueChangedFcn = createCallbackFcn(app, @CalLengthValueChanged, true);
            app.CalLength.Value = '1';
            app.Calibration_lamp = uilamp(app.MeasurementButtonGroup);
            app.Body_axis_lamp = uilamp(app.MeasurementButtonGroup);
            app.Measurement_lamp = uilamp(app.MeasurementButtonGroup);
            app.Export_lamp = uilamp(app.MeasurementButtonGroup);
            app.ExportMeasurementButton = uibutton(app.MeasurementButtonGroup, 'push');
            app.ExportMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMeasurementButtonPushed, true);
            app.ExportMeasurementButton.Text = 'Export Measurement';
            app.CalibratIonEdit = uibutton(app.MeasurementButtonGroup, 'push');
            app.CalibratIonEdit.ButtonPushedFcn = createCallbackFcn(app, @CalibratIonEditButtonPushed, true);
            app.CalibratIonEdit.Text = 'Edit';
            app.BoxyAxisEdit = uibutton(app.MeasurementButtonGroup, 'push');
            app.BoxyAxisEdit.ButtonPushedFcn = createCallbackFcn(app, @BoxyAxisEditButtonPushed, true);
            app.BoxyAxisEdit.Text = 'Edit';
            app.MeasurementEdit = uibutton(app.MeasurementButtonGroup, 'push');
            app.MeasurementEdit.ButtonPushedFcn = createCallbackFcn(app, @MeasurementEditButtonPushed, true);
            app.MeasurementEdit.Text = 'Edit';
            app.CalibrationLabel = uilabel(app.MeasurementButtonGroup);
            app.CalibrationLabel.Text = 'Calibration';
            app.BodyaxislineLabel = uilabel(app.MeasurementButtonGroup);
            app.BodyaxislineLabel.Text = 'Body axis line';
            app.MeasurementLabel = uilabel(app.MeasurementButtonGroup);
            app.MeasurementLabel.Text = 'Measurement';
            app.CalLenPxEditFieldLabel = uilabel(app.MeasurementButtonGroup);
            app.CalLenPxEditFieldLabel.Visible = 'off';
            app.CalLenPxEditFieldLabel.Text = 'CalLenPx';
            app.CalLenPx = uieditfield(app.MeasurementButtonGroup, 'text');
            app.CalLenPx.Visible = 'off';
            app.SegmentsnEditFieldLabel = uilabel(app.MeasurementButtonGroup);
            app.SegmentsnEditFieldLabel.Text = 'Segments (n)';
            app.SegmentBin = uieditfield(app.MeasurementButtonGroup, 'numeric');
            app.SegmentBin.Limits = [0 9999];
            app.SegmentBin.RoundFractionalValues = 'on';
            app.SegmentBin.Value = 10;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.TextArea_3 = uitextarea(app.InstructionsTab);
            app.TextArea_3.Value = {'INSTRUCTIONS '; ''; '1. Import the image to be processed. '; ''; '2. Enter the appropriate data into the boxes with white backgrounds. '; ''; '3. Draw the calibration line using the ruler in the image. '; ''; '4. Draw a line from anterior-to-posterior. '; ''; '5. Make the appropriate measurement(s). Completed steps will be indicated with a green signal. '; ''; '6. Export the measurements when done. If it is a shape, a preview of the image will be generated to the right. If it is a length measurement, nothing will appear. '};
            app.ExportFormatTab = uitab(app.TabGroup);
            app.ExportFormatTab.Title = 'Export Format';
            app.MetadataPanel = uipanel(app.ExportFormatTab);
            app.MetadataPanel.Title = 'Metadata';
            app.SurveyButton = uibutton(app.MetadataPanel, 'state');
            app.SurveyButton.ValueChangedFcn = createCallbackFcn(app, @SurveyButtonValueChanged, true);
            app.SurveyButton.Tag = 'metadata';
            app.SurveyButton.Text = 'Survey';
            app.SurveyButton.Value = true;
            app.TowButton = uibutton(app.MetadataPanel, 'state');
            app.TowButton.ValueChangedFcn = createCallbackFcn(app, @TowButtonValueChanged, true);
            app.TowButton.Tag = 'metadata';
            app.TowButton.Text = 'Tow';
            app.TowButton.Value = true;
            app.DateButton = uibutton(app.MetadataPanel, 'state');
            app.DateButton.ValueChangedFcn = createCallbackFcn(app, @DateButtonValueChanged, true);
            app.DateButton.Tag = 'metadata';
            app.DateButton.Text = 'Date';
            app.DateButton.Value = true;
            app.OrientationButton = uibutton(app.MetadataPanel, 'state');
            app.OrientationButton.ValueChangedFcn = createCallbackFcn(app, @OrientationButtonValueChanged, true);
            app.OrientationButton.Tag = 'metadata';
            app.OrientationButton.Text = 'Orientation';
            app.OrientationButton.Value = true;
            app.TaxaButton = uibutton(app.MetadataPanel, 'state');
            app.TaxaButton.ValueChangedFcn = createCallbackFcn(app, @TaxaButtonValueChanged, true);
            app.TaxaButton.Tag = 'metadata';
            app.TaxaButton.Text = 'Taxa';
            app.TaxaButton.Value = true;
            app.AnimalIDButton = uibutton(app.MetadataPanel, 'state');
            app.AnimalIDButton.ValueChangedFcn = createCallbackFcn(app, @AnimalIDButtonValueChanged, true);
            app.AnimalIDButton.Tag = 'metadata';
            app.AnimalIDButton.Text = 'Animal ID';
            app.AnimalIDButton.Value = true;
            app.MeasurementsPanel = uipanel(app.ExportFormatTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.BodyLengthButton = uibutton(app.MeasurementsPanel, 'state');
            app.BodyLengthButton.ValueChangedFcn = createCallbackFcn(app, @BodyLengthButtonValueChanged, true);
            app.BodyLengthButton.Tag = 'meas';
            app.BodyLengthButton.Text = 'Body Length';
            app.BodyLengthButton.Value = true;
            app.ScaledshapeButton = uibutton(app.MeasurementsPanel, 'state');
            app.ScaledshapeButton.ValueChangedFcn = createCallbackFcn(app, @ScaledshapeButtonValueChanged, true);
            app.ScaledshapeButton.Tag = 'meas';
            app.ScaledshapeButton.Text = 'Scaled shape';
            app.ScaledshapeButton.Value = true;
            app.UnscaledshapeButton = uibutton(app.MeasurementsPanel, 'state');
            app.UnscaledshapeButton.ValueChangedFcn = createCallbackFcn(app, @UnscaledshapeButtonValueChanged, true);
            app.UnscaledshapeButton.Tag = 'meas';
            app.UnscaledshapeButton.Text = 'Unscaled shape';
            app.UnscaledshapeButton.Value = true;
            app.ScaledheightButton = uibutton(app.MeasurementsPanel, 'state');
            app.ScaledheightButton.ValueChangedFcn = createCallbackFcn(app, @ScaledheightButtonValueChanged, true);
            app.ScaledheightButton.Tag = 'meas';
            app.ScaledheightButton.Text = 'Scaled height';
            app.ScaledheightButton.Value = true;
            app.UnscaledheightButton = uibutton(app.MeasurementsPanel, 'state');
            app.UnscaledheightButton.ValueChangedFcn = createCallbackFcn(app, @UnscaledheightButtonValueChanged, true);
            app.UnscaledheightButton.Tag = 'meas';
            app.UnscaledheightButton.Text = 'Unscaled height';
            app.UnscaledheightButton.Value = true;
            app.CalibrationLengthButton = uibutton(app.MeasurementsPanel, 'state');
            app.CalibrationLengthButton.ValueChangedFcn = createCallbackFcn(app, @CalibrationLengthButtonValueChanged, true);
            app.CalibrationLengthButton.Tag = 'meas';
            app.CalibrationLengthButton.Text = 'Calibration Length';
            app.CalibrationLengthButton.Value = true;
            app.DrawnshapeButton = uibutton(app.MeasurementsPanel, 'state');
            app.DrawnshapeButton.ValueChangedFcn = createCallbackFcn(app, @DrawnshapeButtonValueChanged, true);
            app.DrawnshapeButton.Tag = 'meas';
            app.DrawnshapeButton.Text = 'Drawn shape';
            app.NormalizedshapeButton = uibutton(app.MeasurementsPanel, 'state');
            app.NormalizedshapeButton.ValueChangedFcn = createCallbackFcn(app, @NormalizedshapeButtonValueChanged, true);
            app.NormalizedshapeButton.Tag = 'meas';
            app.NormalizedshapeButton.Text = 'Normalized shape';
            app.NormalizedshapeButton.Value = true;
            app.UnitsPanel = uipanel(app.ExportFormatTab);
            app.UnitsPanel.Title = 'Units';
            app.ButtonGroup = uibuttongroup(app.UnitsPanel);
            app.mmButton = uitogglebutton(app.ButtonGroup);
            app.mmButton.Tag = 'units';
            app.mmButton.Text = 'mm';
            app.mmButton.Value = true;
            app.cmButton = uitogglebutton(app.ButtonGroup);
            app.cmButton.Tag = 'units';
            app.cmButton.Text = 'cm';
            app.mButton = uitogglebutton(app.ButtonGroup);
            app.mButton.Tag = 'units';
            app.mButton.Text = 'm';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Select_save = uibutton(app.OptionsTab, 'push');
            app.Select_save.ButtonPushedFcn = createCallbackFcn(app, @Select_saveButtonPushed, true);
            app.Select_save.Text = 'Set Save Directory';
            app.Select_image = uibutton(app.OptionsTab, 'push');
            app.Select_image.Text = 'Set Image Directory';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.TextArea_4 = uitextarea(app.CreditsTab);
            app.TextArea_4.Value = {'INFORMATION'; ''; 'SOUNDSHAPES, v.1.2 (October 2021)'; 'Brandyn M. Lucca, Stony Brook University'; 'Email: brandyn.lucca@stonybrook.edu'; 'Alt-email: brandyn.lucca@gmail.com'; 'GitHub: brandynlucca'};
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.RightPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImagePreview = uiaxes(app.RightPanel);
            xlabel(app.ImagePreview, 'Normalized x')
            ylabel(app.ImagePreview, 'Normalized y')
            app.ImagePreview.YLim = [-1 1];
            app.ImagePreview.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ImagePreview.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1.0'};
            app.Directory_label = uilabel(app.RightPanel);
            app.Directory_label.Text = '';
            app.MeasurementPreviewLabel = uilabel(app.RightPanel);
            app.MeasurementPreviewLabel.Text = 'Measurement Preview';
            app.ImageProccesedDataPanel = uipanel(app.RightPanel);
            app.ImageProccesedDataPanel.Title = 'Image Proccesed Data';
            app.PixelsmmEditFieldLabel = uilabel(app.ImageProccesedDataPanel);
            app.PixelsmmEditFieldLabel.Text = 'Pixels/mm';
            app.PxLength = uieditfield(app.ImageProccesedDataPanel, 'text');
            app.PxLength.Editable = 'off';
            app.LengthmmEditFieldLabel = uilabel(app.ImageProccesedDataPanel);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.Length_mm = uieditfield(app.ImageProccesedDataPanel, 'text');
            app.Length_mm.Editable = 'off';
            app.LengthpxLabel = uilabel(app.ImageProccesedDataPanel);
            app.LengthpxLabel.Text = 'Length (px)';
            app.Length_px = uieditfield(app.ImageProccesedDataPanel, 'text');
            app.Length_px.Editable = 'off';
            app.SegmentsnLabel = uilabel(app.ImageProccesedDataPanel);
            app.SegmentsnLabel.Text = 'Segments (n)';
            app.SegCount = uieditfield(app.ImageProccesedDataPanel, 'text');
            app.SegCount.Editable = 'off';
            app.ImageMetadataPanel = uipanel(app.RightPanel);
            app.ImageMetadataPanel.Title = 'Image Metadata';
            app.FileEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.FileEditFieldLabel.Text = 'File:';
            app.Filename = uieditfield(app.ImageMetadataPanel, 'text');
            app.Filename.Editable = 'off';
            app.FormatEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.FormatEditFieldLabel.Text = 'Format:';
            app.Filetype = uieditfield(app.ImageMetadataPanel, 'text');
            app.Filetype.Editable = 'off';
            app.ImageDateEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.ImageDateEditFieldLabel.Text = 'Image Date:';
            app.ImageDate = uieditfield(app.ImageMetadataPanel, 'text');
            app.ImageDate.Editable = 'off';
            app.WidthpxEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.WidthpxEditFieldLabel.Text = 'Width (px)';
            app.Width_px = uieditfield(app.ImageMetadataPanel, 'text');
            app.Width_px.Editable = 'off';
            app.HeightpxEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.Height_px = uieditfield(app.ImageMetadataPanel, 'text');
            app.Height_px.Editable = 'off';
            app.CurrentDirectoryLabel = uilabel(app.ImageMetadataPanel);
            app.CurrentDirectoryLabel.Text = {'Current '; 'Directory'; ''};
            app.Directory = uieditfield(app.ImageMetadataPanel, 'text');
            app.Directory.Editable = 'off';
            app.CurrentImageEditFieldLabel = uilabel(app.ImageMetadataPanel);
            app.CurrentImageEditFieldLabel.Text = {'Current '; 'Image'; ''};
            app.Image = uieditfield(app.ImageMetadataPanel, 'text');
            app.Image.Editable = 'off';
            app.Clear_Image = uibutton(app.RightPanel, 'push');
            app.Clear_Image.ButtonPushedFcn = createCallbackFcn(app, @Clear_ImagePushed, true);
            app.Clear_Image.Text = 'Clear Image';
            app.Update_Data = uibutton(app.RightPanel, 'push');
            app.Update_Data.ButtonPushedFcn = createCallbackFcn(app, @Update_DataPushed, true);
            app.Update_Data.Text = 'Update Data';
            app.Clear_Preview = uibutton(app.RightPanel, 'push');
            app.Clear_Preview.ButtonPushedFcn = createCallbackFcn(app, @Clear_PreviewButtonPushed, true);
            app.Clear_Preview.Text = 'Clear Preview';
            app.Clear_All = uibutton(app.RightPanel, 'push');
            app.Clear_All.ButtonPushedFcn = createCallbackFcn(app, @Clear_AllButtonPushed, true);
            app.Clear_All.Text = 'CLEAR ALL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundShapesApp_v2_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end